classdef wave_clus < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        UIAxes               matlab.ui.control.UIAxes
        LoadData             matlab.ui.control.Button
        PlotpolytrodeButton  matlab.ui.control.Button
        SaveclustersButton   matlab.ui.control.Button
        SetparametersButton  matlab.ui.control.Button
        UndoButton           matlab.ui.control.Button
        PlotallCheckBox      matlab.ui.control.CheckBox
        PlotaverageCheckBox  matlab.ui.control.CheckBox
        UIAxes2              matlab.ui.control.UIAxes
        UIAxes2_2            matlab.ui.control.UIAxes
        UIAxes2_3            matlab.ui.control.UIAxes
        UIAxes2_4            matlab.ui.control.UIAxes
        UIAxes2_5            matlab.ui.control.UIAxes
        UIAxes2_6            matlab.ui.control.UIAxes
        UIAxes2_7            matlab.ui.control.UIAxes
        UIAxes2_8            matlab.ui.control.UIAxes
        UIAxes2_9            matlab.ui.control.UIAxes
        UIAxes2_10           matlab.ui.control.UIAxes
        Label                matlab.ui.control.Label
    end

    methods (Access = private)

        % Button pushed function: LoadData
        function LoadDataButtonPushed(app, event)
            % select file
            if ischar(eventdata)
    if isempty(fileparts(eventdata))
        filename = eventdata;
        pathname = [pwd filesep];
    else
        [pathname filename ext]=fileparts(eventdata);
        pathname = [pathname filesep];
        filename = [filename ext];
    end
    hObject = handles.load_data_button;
else
    [filename, pathname] = uigetfile('*.*','Select file'); % Use only the supported extensions can bring case-sensitive related problems.
end

% if any file was selected, cancel the loading
if ~ischar(pathname)
    return
end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.LoadData = uibutton(app.UIFigure, 'push');
            app.LoadData.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadData.Text = 'Load data';
            app.PlotpolytrodeButton = uibutton(app.UIFigure, 'push');
            app.PlotpolytrodeButton.Text = 'Plot polytrode';
            app.SaveclustersButton = uibutton(app.UIFigure, 'push');
            app.SaveclustersButton.Text = 'Save clusters';
            app.SetparametersButton = uibutton(app.UIFigure, 'push');
            app.SetparametersButton.Text = 'Set parameters';
            app.UndoButton = uibutton(app.UIFigure, 'push');
            app.UndoButton.Text = 'Undo';
            app.PlotallCheckBox = uicheckbox(app.UIFigure);
            app.PlotallCheckBox.Text = 'Plot all';
            app.PlotaverageCheckBox = uicheckbox(app.UIFigure);
            app.PlotaverageCheckBox.Text = 'Plot average';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            app.UIAxes2_2 = uiaxes(app.UIFigure);
            title(app.UIAxes2_2, 'Title')
            app.UIAxes2_3 = uiaxes(app.UIFigure);
            title(app.UIAxes2_3, 'Title')
            app.UIAxes2_4 = uiaxes(app.UIFigure);
            title(app.UIAxes2_4, 'Title')
            app.UIAxes2_5 = uiaxes(app.UIFigure);
            title(app.UIAxes2_5, 'Title')
            app.UIAxes2_6 = uiaxes(app.UIFigure);
            title(app.UIAxes2_6, 'Title')
            app.UIAxes2_7 = uiaxes(app.UIFigure);
            title(app.UIAxes2_7, 'Title')
            app.UIAxes2_8 = uiaxes(app.UIFigure);
            title(app.UIAxes2_8, 'Title')
            app.UIAxes2_9 = uiaxes(app.UIFigure);
            title(app.UIAxes2_9, 'Title')
            app.UIAxes2_10 = uiaxes(app.UIFigure);
            title(app.UIAxes2_10, 'Title')
            app.Label = uilabel(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = wave_clus

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end